classdef Final_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EncryptyourimageshereLabel  matlab.ui.control.Label
        DarkLightSwitch             matlab.ui.control.Switch
        DarkLightSwitchLabel        matlab.ui.control.Label
        OptionsButton               matlab.ui.control.Button
        DecryptionButton            matlab.ui.control.Button
        EncryptionButton            matlab.ui.control.Button
        WelcometoFranciscanEncryptionServicesLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EncryptionButton
        function EncryptionButtonPushed(app, event)
            [filename, pathname] = uigetfile("*.*", "Pick an image file: ");
            imName = strcat(pathname, filename);
            i = imread(imName);
            [x,y,~] = size(i);
            origim = containers.Map('KeySet','double','ValueType','any');
            num1 = 0
            num2 = 0
            for l = 1:x*y
                
                    r = i(:,:,1);
                    g = i(:,:,1);
                    b = i(:,:,1);
                    [r(num1+1,num2+1) g(num1+1,num2+1) b(num1+1,num2+1)]
                    num1 = num1 + 1;
                    num2 = num2 + 1;
                    
            end
            i2 = i;
            [x,y,~] = size(i2);
            for l = 1:x
                for w = 1:y
                    i2(l,w,1) = -255; i2(l,w,2) = -255; i2(l,w,3) = -255;
                end
            end
%             i3 = i;
%             [x,y,~] = size(i3);
%             
%             imshowpair(i, i3, "montage")
            imshow(i)
            imtool(i)
            %imshowpair(i,step3,"montage")
%other encryption ideas
            %dim = filter2(fspecial('sobel'),i);
            %minMat = min(dim(:));
            %maxMat = max(dim(:));
            %imMat = mat2gray(i, [minMat maxMat]);
            %bytes = figToImStream("imageFormat", i);

        end

        % Button pushed function: DecryptionButton
        function DecryptionButtonPushed(app, event)
            [filename, pathname] = uigetfile("*.*", "Pick an image file: ");
            imName = strcat(pathname, filename);
            i = imread(imName);
            imshow(i);


%decryption ideas
            %reconstructed_image = reshape(typecast(int8(arrayfun(@byteValue, YourByteArray)),'uint8'), [4000, 3000, 3]);

        end

        % Value changed function: DarkLightSwitch
        function DarkLightSwitchValueChanged(app, event)
            value = app.DarkLightSwitch.Value;
            app.DarkLightSwitch.BackgroundColor = [0.96,0.96,0.96];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcometoFranciscanEncryptionServicesLabel = uilabel(app.UIFigure);
            app.WelcometoFranciscanEncryptionServicesLabel.Text = 'Welcome to Franciscan Encryption Services!';
            app.EncryptionButton = uibutton(app.UIFigure, 'push');
            app.EncryptionButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptionButtonPushed, true);
            app.EncryptionButton.Text = 'Encryption';
            app.DecryptionButton = uibutton(app.UIFigure, 'push');
            app.DecryptionButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptionButtonPushed, true);
            app.DecryptionButton.Text = 'Decryption';
            app.OptionsButton = uibutton(app.UIFigure, 'push');
            app.OptionsButton.Text = 'Options';
            app.DarkLightSwitchLabel = uilabel(app.UIFigure);
            app.DarkLightSwitchLabel.Text = 'Dark/Light';
            app.DarkLightSwitch = uiswitch(app.UIFigure, 'slider');
            app.DarkLightSwitch.Items = {'', ''};
            app.DarkLightSwitch.ValueChangedFcn = createCallbackFcn(app, @DarkLightSwitchValueChanged, true);
            app.DarkLightSwitch.Value = '';
            app.EncryptyourimageshereLabel = uilabel(app.UIFigure);
            app.EncryptyourimageshereLabel.Text = 'Encrypt your images here';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end