classdef appTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Image          matlab.ui.control.Image
        EncryptDecryptyourimageshereLabel  matlab.ui.control.Label
        WelcomeToFranciscanEncryptionServicesLabel  matlab.ui.control.Label
        DecryptButton  matlab.ui.control.Button
        EncryptButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EncryptButton
        function EncryptButtonPushed(app, event)
            clc; close all; clear; 
            [filename, pathname] = uigetfile("*.*", "Pick an image file: ");
            imName = strcat(pathname, filename);
            im = imread(imName);
            title = {'Enter Encryption Code:'};
            subtitle = "Encryption Code";
            liminput = {'10'};
            opts.Interpreter='tex';
            userPrompt = inputdlg(title, subtitle,[1 11], liminput, opts);
            userPrompt = str2double(userPrompt{1});
            app.UserInputDataTextArea.Value = userPrompt;
            outim = im;
            [rows, columns, numberOfColors] = size(im);
            colChange = 300;
            save(['file_' num2str(userPrompt)], "im");
            for x = 1:columns
                for y = 1:rows
                    f = im(y,x,:);
                    fprintf('for row %d, column %d, originial brightness = %d\n', y, x, f)
                    outim(y, x, :) = f + colChange;
                end
            end
            
            imshow(outim)
        end

        % Button pushed function: DecryptButton
        function DecryptButtonPushed(app, event)
            clc; close all; clear; 
            [filename, pathname] = uigetfile("*.*", "Pick an image file: ");
            imName = strcat(pathname, filename);
            encim = imread(imName);
            title = {'Enter Encryption Code:'};
            subtitle = "Encryption Code";
            liminput = {'10'};
            opts.Interpreter='tex';
            userPrompt = inputdlg(title, subtitle,[1 11], liminput, opts);
            userPrompt = str2double(userPrompt{1});
            app.UserInputDataTextArea.Value = userPrompt;
            load(['file_' num2str(userPrompt)], "im");
            [row, col, numCol] = size(im);
            reEncrypted = imresize(encim, [row, col]);
            encim = reEncrypted;
            decim = encim;
            [rowsd, columnsd, numberOfColorsd] = size(decim);
            for t = 1:columnsd
                for v = 1:rowsd
                    f = im(v,t,:);
                    fprintf('for row %d, column %d, originial brightness = %d\n', v, t, f)
                    decim(v, t, :) = f;
                end
            end
            
            imshow(decim)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EncryptButton = uibutton(app.UIFigure, 'push');
            app.EncryptButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptButtonPushed, true);
            app.EncryptButton.Text = 'Encrypt';
            app.DecryptButton = uibutton(app.UIFigure, 'push');
            app.DecryptButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptButtonPushed, true);
            app.DecryptButton.Text = 'Decrypt';
            app.WelcomeToFranciscanEncryptionServicesLabel = uilabel(app.UIFigure);
            app.WelcomeToFranciscanEncryptionServicesLabel.Text = 'Welcome To Franciscan Encryption Services!';
            app.EncryptDecryptyourimageshereLabel = uilabel(app.UIFigure);
            app.EncryptDecryptyourimageshereLabel.Text = 'Encrypt/Decrypt your images here: ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'franciscan.jpeg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end