classdef appTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button2   matlab.ui.control.Button
        Button    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            clc; close all; clear; 
            [filename, pathname] = uigetfile("*.*", "Pick an image file: ");
            imName = strcat(pathname, filename);
            im = imread(imName);
            %finding the output image
            outim = im;
            [rows, columns, numberOfColors] = size(im);
            colChange = 300;
            save('inImages', "im")
            for x = 1:columns
                for y = 1:rows
                    f = im(y,x,:);
                    fprintf('for row %d, column %d, originial brightness = %d\n', y, x, f)
                    outim(y, x, :) = f + colChange;
                end
            end
            
            imshow(outim)
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            clc; close all; clear; 
            [filename, pathname] = uigetfile("*.*", "Pick an image file: ");
            imName = strcat(pathname, filename);
            encim = imread(imName);
            load('inImages','im')
            [row, col, numCol] = size(im);
            reEncrypted = imresize(encim, [row, col]);
            encim = reEncrypted;
            decim = encim;
            [rowsd, columnsd, numberOfColorsd] = size(decim);
            for t = 1:columnsd
                for v = 1:rowsd
                    f = im(v,t,:);
                    fprintf('for row %d, column %d, originial brightness = %d\n', v, t, f)
                    decim(v, t, :) = f;
                end
            end
            
            imshow(decim)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end